classdef PlaneStress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        MadewithloveinAUCEDLabel        matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        omegaLabel                      matlab.ui.control.Label
        YieldStressEditFieldLabel       matlab.ui.control.Label
        AnimateButton                   matlab.ui.control.Button
        DurationsEditField              matlab.ui.control.NumericEditField
        DurationsEditFieldLabel         matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        DeformationscaleEditField       matlab.ui.control.NumericEditField
        DeformationscaleEditFieldLabel  matlab.ui.control.Label
        WritetoANSYSButton              matlab.ui.control.Button
        InternalForcesButton            matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PlaneStrainButton               matlab.ui.control.RadioButton
        PlaneStressButton               matlab.ui.control.RadioButton
        KButton                         matlab.ui.control.Button
        StrainButton                    matlab.ui.control.Button
        NodesForceButton                matlab.ui.control.Button
        NodesdButton                    matlab.ui.control.Button
        StressButton                    matlab.ui.control.Button
        CornerForceButton               matlab.ui.control.Button
        ShowLabel                       matlab.ui.control.Label
        CornerdButton                   matlab.ui.control.Button
        MaterialPropertiesLabel         matlab.ui.control.Label
        ThicknessEditFieldLabel         matlab.ui.control.Label
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        EEditFieldLabel                 matlab.ui.control.Label
        ProcessingLabel                 matlab.ui.control.Label
        BoundaryConditionsButtonGroup   matlab.ui.container.ButtonGroup
        ForceButton                     matlab.ui.control.ToggleButton
        DisplacementButton              matlab.ui.control.ToggleButton
        MeshOptionsLabel                matlab.ui.control.Label
        PlaneDimensionsLabel            matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        YieldStressEditField            matlab.ui.control.NumericEditField
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        EEditField                      matlab.ui.control.NumericEditField
        VerticalDivisionsEditField      matlab.ui.control.NumericEditField
        VerticalDivisionsEditFieldLabel  matlab.ui.control.Label
        HorizontalDivisionsEditField    matlab.ui.control.NumericEditField
        HorizontalDivisionsEditFieldLabel  matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        WidthEditField_2                matlab.ui.control.NumericEditField
        WidthEditField_2Label           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        NodeCoordinates % Description
        ElementNodes % Description
        CornerNodes % Description
        SideNodes % Description
        Displacements % Description
        Forces
        Boundary % Description
        surfForce
        poiForce
        stress % Description
        newFig1 % Description
        newFig2
        newFig3
        newFig4
        newFig5
        newFig6
        newFig7
        newFig8
        newFig9
        originalK % Description
        strain % Description
        Q % Description
        cornerD % Description
        reaction % Description
        cReaction % Description
        internalForce % Description
        nodalForce % Description
        % Description
        draw % Description
        animation
    end

    methods (Access = private)

        function results = graph(app)


            cla(app.UIAxes);
            if app.WidthEditField_2.Value~=0 && app.HeightEditField.Value~=0
                [p,cl]=GenerateMesh(app.WidthEditField_2.Value,app.HeightEditField.Value,app.HorizontalDivisionsEditField.Value,app.VerticalDivisionsEditField.Value);
                hold(app.UIAxes, 'all');
                app.UIAxes.XLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];
                app.UIAxes.YLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];
                if app.draw==0
                    patch(app.UIAxes,'faces', cl, 'Vertices', p, 'facecolor', 'c', 'edgecolor', 'k');
                    plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                end
                app.NodeCoordinates=p;
                app.ElementNodes=cl;

                %% Ploting Deformed shape
                factor=app.DeformationscaleEditField.Value;
                if app.draw==1
                    if app.animation==1


                        %% animation
                        if app.FPSEditField.Value>0 && app.DurationsEditField.Value>0
                            frames=0;
                            for index=1:app.FPSEditField.Value*app.DurationsEditField.Value
                                cla(app.UIAxes);
                                hold(app.UIAxes, 'all');
                                patch(app.UIAxes,'faces', cl, 'Vertices', p+frames*factor*app.Q(:,1:2), 'facecolor', 'g','edgecolor', 'k');
                                plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                                % patch(app.UIAxes,'faces', cl, 'Vertices', p, 'FaceAlpha', 0, 'facecolor', 'c', 'edgecolor', 'k');
                                % plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                                frames=frames+1/(app.FPSEditField.Value*app.DurationsEditField.Value);
                                pause(1/app.FPSEditField.Value);
                            end
                        end
                        app.animation=0;
                    end
                    cla(app.UIAxes);
                    patch(app.UIAxes,'faces', cl, 'Vertices', p+factor*app.Q(:,1:2),'FaceAlpha', 0.3, 'facecolor', 'g','edgecolor', 'g');
                    plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                    patch(app.UIAxes,'faces', cl, 'Vertices', p, 'FaceAlpha', 0, 'edgecolor', 'k','LineStyle','--');
                    plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                end


                %% Finding corner nodes
                app.CornerNodes=[1,
                    1+app.HorizontalDivisionsEditField.Value,
                    (1+app.HorizontalDivisionsEditField.Value)*(app.VerticalDivisionsEditField.Value+1),
                    (1+app.HorizontalDivisionsEditField.Value)*app.VerticalDivisionsEditField.Value+1];
                %End of finding corner nodes

                %% Finding Side nodes
                app.SideNodes=zeros(4,max([app.VerticalDivisionsEditField.Value+1,app.HorizontalDivisionsEditField.Value+1]));


                app.SideNodes(1,1:app.HorizontalDivisionsEditField.Value+1)=[1:(app.HorizontalDivisionsEditField.Value+1)];
                app.SideNodes(2,1:app.VerticalDivisionsEditField.Value+1)=[(1:app.VerticalDivisionsEditField.Value+1).*(app.HorizontalDivisionsEditField.Value+1)];
                app.SideNodes(3,1:app.HorizontalDivisionsEditField.Value+1)=[(1+app.HorizontalDivisionsEditField.Value)*app.VerticalDivisionsEditField.Value+1:(1+app.HorizontalDivisionsEditField.Value)*(app.VerticalDivisionsEditField.Value+1)];
                app.SideNodes(4,1:app.VerticalDivisionsEditField.Value+1)=[(1:app.VerticalDivisionsEditField.Value+1).*(app.HorizontalDivisionsEditField.Value+1)-app.HorizontalDivisionsEditField.Value];

                %End of Finding Side nodes

                app.setBoundaries();

            end


            %% Plotting Boundary conditions
            strock=max(app.WidthEditField_2.Value,app.HeightEditField.Value)/20;

            appliedForce=app.poiForce+app.surfForce;

            for dm=1:size(app.NodeCoordinates,1)
                %% Plotting Boundary conditions
                if app.Boundary(2*dm-1,1)==0
                    plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)-strock,app.NodeCoordinates(dm,1)-strock,app.NodeCoordinates(dm,1)], ...
                        [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+(strock/2),app.NodeCoordinates(dm,2)-(strock/2),app.NodeCoordinates(dm,2)],'Color','b');
                elseif ~isnan(app.Boundary(2*dm-1,1))
                    if app.Boundary(2*dm-1,1)>0
                        plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+(strock/2),app.NodeCoordinates(dm,1)+strock,app.NodeCoordinates(dm,1)+1.5*strock], ...
                            [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+(strock/2),app.NodeCoordinates(dm,2)-(strock/2),app.NodeCoordinates(dm,2)],'Color','b');
                    else
                        plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)-(strock/2),app.NodeCoordinates(dm,1)-strock,app.NodeCoordinates(dm,1)-1.5*strock], ...
                            [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+(strock/2),app.NodeCoordinates(dm,2)-(strock/2),app.NodeCoordinates(dm,2)],'Color','b');
                    end
                end

                if app.Boundary(2*dm,1)==0
                    plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+(strock/2),app.NodeCoordinates(dm,1)-(strock/2),app.NodeCoordinates(dm,1)], ...
                        [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)-(strock),app.NodeCoordinates(dm,2)-(strock),app.NodeCoordinates(dm,2)],'Color','b');
                elseif ~isnan(app.Boundary(2*dm,1))
                    if app.Boundary(2*dm,1)>0
                        plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+(strock/2),app.NodeCoordinates(dm,1)-(strock/2),app.NodeCoordinates(dm,1)], ...
                            [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+(strock/2),app.NodeCoordinates(dm,2)+(strock),app.NodeCoordinates(dm,2)+1.5*strock],'Color','b');
                    else
                        plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+(strock/2),app.NodeCoordinates(dm,1)-(strock/2),app.NodeCoordinates(dm,1)], ...
                            [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)-(strock/2),app.NodeCoordinates(dm,2)-(strock),app.NodeCoordinates(dm,2)-1.5*strock],'Color','b');
                    end
                end

                %% Plotting Forces

                if appliedForce(2*dm-1,1)>0
                    plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+2*strock,app.NodeCoordinates(dm,1)+strock,app.NodeCoordinates(dm,1)+2*strock,app.NodeCoordinates(dm,1)+strock], ...
                        [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+0.5*strock,app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)-0.5*strock],'Color','r');
                elseif appliedForce(2*dm-1,1)<0
                    plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)-2*strock,app.NodeCoordinates(dm,1)-strock,app.NodeCoordinates(dm,1)+2*strock,app.NodeCoordinates(dm,1)-strock], ...
                        [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+0.5*strock,app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)-0.5*strock],'Color','r');
                end
                if appliedForce(2*dm,1)>0
                    plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+0.5*strock,app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)-0.5*strock], ...
                        [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)+2*strock,app.NodeCoordinates(dm,2)+strock,app.NodeCoordinates(dm,2)+2*strock,app.NodeCoordinates(dm,2)+strock],'Color','r');
                elseif appliedForce(2*dm,1)<0
                    plot(app.UIAxes,[app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)+0.5*strock,app.NodeCoordinates(dm,1),app.NodeCoordinates(dm,1)-0.5*strock], ...
                        [app.NodeCoordinates(dm,2),app.NodeCoordinates(dm,2)-2*strock,app.NodeCoordinates(dm,2)-strock,app.NodeCoordinates(dm,2)-2*strock,app.NodeCoordinates(dm,2)-strock],'Color','r');
                end

            end

            pbaspect(app.UIAxes, [1 1 1]);

        end

        function results = setBoundaries(app)

            t = app.ThicknessEditField.Value;
            coord = app.NodeCoordinates;
            nodes = app.ElementNodes;
            hDiv=app.HorizontalDivisionsEditField.Value;
            vDiv=app.VerticalDivisionsEditField.Value;
            width=app.WidthEditField_2.Value;
            height=app.HeightEditField.Value;


            %% Force Matrix
            % Point Loads
            pointForce=zeros(size(coord,1)*2,1);

            pointForce(app.CornerNodes(1:4)*2-1,1)=app.Forces(1:4,1);
            pointForce(app.CornerNodes(1:4)*2,1)=app.Forces(1:4,2);

            app.poiForce=pointForce;

            %Surface Loads
            surfaceForce=zeros(size(coord,1)*2,1);
            surfaceForce([app.SideNodes(1,1:hDiv+1)*2-1],1)=surfaceForce([app.SideNodes(1,1:hDiv+1)*2-1],1)+(app.Forces(5,1)*width*t)/(hDiv+1);
            surfaceForce([app.SideNodes(1,1:hDiv+1)*2],1)=surfaceForce([app.SideNodes(1,1:hDiv+1)*2],1)+(app.Forces(5,2)*width*t)/(hDiv+1);
            surfaceForce([app.SideNodes(2,1:vDiv+1)*2-1],1)=surfaceForce([app.SideNodes(2,1:vDiv+1)*2-1],1)+(app.Forces(6,1)*height*t)/(vDiv+1);
            surfaceForce([app.SideNodes(2,1:vDiv+1)*2],1)=surfaceForce([app.SideNodes(2,1:vDiv+1)*2],1)+(app.Forces(6,2)*height*t)/(vDiv+1);
            surfaceForce([app.SideNodes(3,1:hDiv+1)*2-1],1)=surfaceForce([app.SideNodes(3,1:hDiv+1)*2-1],1)+(app.Forces(7,1)*width*t)/(hDiv+1);
            surfaceForce([app.SideNodes(3,1:hDiv+1)*2],1)=surfaceForce([app.SideNodes(3,1:hDiv+1)*2],1)+(app.Forces(7,2)*width*t)/(hDiv+1);
            surfaceForce([app.SideNodes(4,1:vDiv+1)*2-1],1)=surfaceForce([app.SideNodes(4,1:vDiv+1)*2-1],1)+(app.Forces(8,1)*height*t)/(vDiv+1);
            surfaceForce([app.SideNodes(4,1:vDiv+1)*2],1)=surfaceForce([app.SideNodes(4,1:vDiv+1)*2],1)+(app.Forces(8,2)*height*t)/(vDiv+1);

            app.surfForce=surfaceForce;
            %Body Loads


            totalForce=pointForce+surfaceForce;


            %% Boundary Conditions
            boundaries=NaN(size(coord,1)*2,1);


            for dm=1:hDiv+1
                if ~isnan(app.Displacements(5,1))
                    boundaries([app.SideNodes(1,1:hDiv+1)*2-1],1)=app.Displacements(5,1);
                end
                if ~isnan(app.Displacements(5,2))
                    boundaries([app.SideNodes(1,1:hDiv+1)*2],1)=app.Displacements(5,2);
                end
                if ~isnan(app.Displacements(7,1))
                    boundaries([app.SideNodes(3,1:hDiv+1)*2-1],1)=app.Displacements(7,1);
                end
                if ~isnan(app.Displacements(7,2))

                    boundaries([app.SideNodes(3,1:hDiv+1)*2],1)=app.Displacements(7,2);
                end
            end


            for dm=1:vDiv+1

                if ~isnan(app.Displacements(6,1))
                    boundaries([app.SideNodes(2,1:vDiv+1)*2-1],1)= app.Displacements(6,1);
                end
                if ~isnan(app.Displacements(6,2))
                    boundaries([app.SideNodes(2,1:vDiv+1)*2],1)=app.Displacements(6,2);
                end
                if ~isnan(app.Displacements(8,1))

                    boundaries([app.SideNodes(4,1:vDiv+1)*2-1],1)=app.Displacements(8,1);
                end
                if ~isnan(app.Displacements(8,2))
                    boundaries([app.SideNodes(4,1:vDiv+1)*2],1)=app.Displacements(8,2);
                end

            end

            for dm=1:4
                if ~isnan(app.Displacements(dm,1))
                    boundaries(app.CornerNodes(dm)*2-1,1)=app.Displacements(dm,1);
                end
                if ~isnan(app.Displacements(dm,2))

                    boundaries(app.CornerNodes(dm)*2,1)=app.Displacements(dm,2);
                end
            end

            app.Boundary=boundaries;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ProcessingLabel.Text="Processing...";
            app.Displacements=NaN(8,2);
            app.Forces=zeros(8,2);
            app.UITable.Data=app.Displacements;
            app.ProcessingLabel.Text="Ready for inputs";
            app.draw=0;
            app.animation=0;
        end

        % Value changed function: WidthEditField_2
        function WidthEditField_2ValueChanged(app, event)
            value = app.WidthEditField_2.Value;
            app.draw=0;
            app.graph();
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.draw=0;
            app.graph();
        end

        % Value changed function: HorizontalDivisionsEditField
        function HorizontalDivisionsEditFieldValueChanged(app, event)
            value = app.HorizontalDivisionsEditField.Value;
            app.draw=0;
            app.graph();
        end

        % Value changed function: VerticalDivisionsEditField
        function VerticalDivisionsEditFieldValueChanged(app, event)
            value = app.VerticalDivisionsEditField.Value;
            app.draw=0;
            app.graph();
        end

        % Selection changed function: BoundaryConditionsButtonGroup
        function BoundaryConditionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.BoundaryConditionsButtonGroup.SelectedObject;

            if selectedButton.Text=="Displacement"
                app.UITable.Data=app.Displacements;
            elseif selectedButton.Text=="Force"
                app.UITable.Data=app.Forces;
            end
            app.draw=0;
            app.graph()
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            selectedButton = app.BoundaryConditionsButtonGroup.SelectedObject;

            if selectedButton.Text=="Displacement"
                app.Displacements=app.UITable.Data;

            elseif selectedButton.Text=="Force"
                app.Forces=app.UITable.Data;

            end
            app.draw=0;
            app.graph()

        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.ProcessingLabel.Text="Stuck ...";
            E = app.EEditField.Value;
            neu = app.PoissonsRatioEditField.Value;
            kGlobal = zeros(length(app.NodeCoordinates)*2);
            t = app.ThicknessEditField.Value;
            coord = app.NodeCoordinates;
            nodes = app.ElementNodes;
            hDiv=app.HorizontalDivisionsEditField.Value;
            vDiv=app.VerticalDivisionsEditField.Value;
            width=app.WidthEditField_2.Value;
            height=app.HeightEditField.Value;

            %Plane Stress

            %% Calculating D
            if app.PlaneStressButton.Value
                radioStress = app.PlaneStressButton.Value;
                D = E / (1-neu^2) * [1 neu 0;
                    neu 1 0;
                    0 0 (1-neu)/2];
            elseif app.PlaneStrainButton.Value
                radioStrain = app.PlaneStrainButton.Value;
                D = (E/((1+neu)*(1-2*neu)))*[1-neu neu 0;
                    neu 1-neu 0;
                    0 0 (1-2*neu)/2];
            end
            %%

            % Calculating kGlobal
            % For each element
            for index = 1:size(app.ElementNodes,1)
                %% Calculating Area
                A = (coord(nodes(index,1),1)*(coord(nodes(index,2),2)-coord(nodes(index,3),2)) ...
                    +coord(nodes(index,2),1)*(coord(nodes(index,3),2)-coord(nodes(index,1),2)) ...
                    +coord(nodes(index,3),1)*(coord(nodes(index,1),2)-coord(nodes(index,2),2)))/2;

                %% Calculating B using beta and gamma
                beta = [coord(nodes(index,2),2) - coord(nodes(index,3),2),
                    coord(nodes(index,3),2) - coord(nodes(index,1),2),
                    coord(nodes(index,1),2) - coord(nodes(index,2),2)];
                gamma = [coord(nodes(index,3),1) - coord(nodes(index,2),1),
                    coord(nodes(index,1),1) - coord(nodes(index,3),1),
                    coord(nodes(index,2),1) - coord(nodes(index,1),1)];
                B = (1/(2*A)) * [beta(1) 0 beta(2) 0 beta(3) 0;
                    0 gamma(1) 0 gamma(2) 0 gamma(3);
                    gamma(1) beta(1) gamma(2) beta(2) gamma(3) beta(3)];

                %% Calculating local k
                k = t*A*B'*D*B;

                %% kGlobal assembly
                % Rows 1:2 with columns 1:6
                kGlobal((nodes(index,1)*2)-1:nodes(index,1)*2, (nodes(index,1)*2)-1:nodes(index,1)*2)=...
                    kGlobal((nodes(index,1)*2)-1:nodes(index,1)*2, (nodes(index,1)*2)-1:nodes(index,1)*2)...
                    +k(1:2,1:2);
                kGlobal((nodes(index,1)*2)-1:nodes(index,1)*2, (nodes(index,2)*2)-1:nodes(index,2)*2)=...
                    kGlobal((nodes(index,1)*2)-1:nodes(index,1)*2, (nodes(index,2)*2)-1:nodes(index,2)*2)...
                    +k(1:2,3:4);
                kGlobal((nodes(index,1)*2)-1:nodes(index,1)*2, (nodes(index,3)*2)-1:nodes(index,3)*2)=...
                    kGlobal((nodes(index,1)*2)-1:nodes(index,1)*2, (nodes(index,3)*2)-1:nodes(index,3)*2)...
                    +k(1:2,5:6);

                % Rows 3:4 with columns 1:6
                kGlobal((nodes(index,2)*2)-1:nodes(index,2)*2, (nodes(index,1)*2)-1:nodes(index,1)*2)=...
                    kGlobal((nodes(index,2)*2)-1:nodes(index,2)*2, (nodes(index,1)*2)-1:nodes(index,1)*2)...
                    +k(3:4,1:2);
                kGlobal((nodes(index,2)*2)-1:nodes(index,2)*2, (nodes(index,2)*2)-1:nodes(index,2)*2)=...
                    kGlobal((nodes(index,2)*2)-1:nodes(index,2)*2, (nodes(index,2)*2)-1:nodes(index,2)*2)...
                    +k(3:4,3:4);
                kGlobal((nodes(index,2)*2)-1:nodes(index,2)*2, (nodes(index,3)*2)-1:nodes(index,3)*2)=...
                    kGlobal((nodes(index,2)*2)-1:nodes(index,2)*2, (nodes(index,3)*2)-1:nodes(index,3)*2)...
                    +k(3:4,5:6);

                % Rows 5:6 with columns 1:6
                kGlobal((nodes(index,3)*2)-1:nodes(index,3)*2, (nodes(index,1)*2)-1:nodes(index,1)*2)=...
                    kGlobal((nodes(index,3)*2)-1:nodes(index,3)*2, (nodes(index,1)*2)-1:nodes(index,1)*2)...
                    +k(5:6,1:2);
                kGlobal((nodes(index,3)*2)-1:nodes(index,3)*2, (nodes(index,2)*2)-1:nodes(index,2)*2)=...
                    kGlobal((nodes(index,3)*2)-1:nodes(index,3)*2, (nodes(index,2)*2)-1:nodes(index,2)*2)...
                    +k(5:6,3:4);
                kGlobal((nodes(index,3)*2)-1:nodes(index,3)*2, (nodes(index,3)*2)-1:nodes(index,3)*2)=...
                    kGlobal((nodes(index,3)*2)-1:nodes(index,3)*2, (nodes(index,3)*2)-1:nodes(index,3)*2)...
                    +k(5:6,5:6);
                %% End of kGlobal Assembly
                app.originalK=kGlobal;


            end


            app.setBoundaries();
            boundaries=app.Boundary;
            pointForce=app.poiForce;
            surfaceForce=app.surfForce;
            %% Calculating Body forces
            bodyForce=zeros(size(app.NodeCoordinates,1)*2,1);
            for index=1:app.ElementNodes
            centeriod=[(app.NodeCoordinates(app.ElementNodes(index,1),1)+app.NodeCoordinates(app.ElementNodes(index,2),1)+app.NodeCoordinates(app.ElementNodes(index,3),1))/3,...
                (app.NodeCoordinates(app.ElementNodes(index,1),2)+app.NodeCoordinates(app.ElementNodes(index,2),2)+app.NodeCoordinates(app.ElementNodes(index,3),2))/3];
            arm=sqrt((centeriod(1)-app.XEditField.Value)^2+(centeriod(2)-app.YEditField.Value)^2);
            acc=arm*app.EditField.Value^2;
            accVector=[((app.XEditField.Value-centeriod(1))*acc)/arm,((app.YEditField.Value-centeriod(2))*acc)/arm];
            bodyForce([app.ElementNodes(index,1)*2-1,app.ElementNodes(index,1)*2,app.ElementNodes(index,2)*2-1,app.ElementNodes(index,2)*2,app.ElementNodes(index,3)*2-1,app.ElementNodes(index,3)*2],1)=...
            bodyForce([app.ElementNodes(index,1)*2-1,app.ElementNodes(index,1)*2,app.ElementNodes(index,2)*2-1,app.ElementNodes(index,2)*2,app.ElementNodes(index,3)*2-1,app.ElementNodes(index,3)*2],1)+...
            [accVector(1);accVector(2)-1;accVector(1);accVector(2)-1;accVector(1);accVector(2)-1]*(app.EditField_2.Value*A*t/3);
            end


            totalForce=pointForce+surfaceForce+bodyForce;

            %% Solve
            c=max(abs(diag(kGlobal)))*1e6 ;

            for dm=1:size(boundaries,1)

                if ~isnan(boundaries(dm))

                    kGlobal(dm,dm)=kGlobal(dm,dm)+c;
                    totalForce(dm,1)=totalForce(dm,1)+c*boundaries(dm);
                end
            end
            Q=kGlobal\totalForce;
            %% Calculating Stresses
            app.nodalForce=zeros(size(app.NodeCoordinates,1),2);
            for index=1:size(app.ElementNodes,1)
                elementDisplacement=[Q(app.ElementNodes(index,1)*2-1,1);Q(app.ElementNodes(index,1)*2,1);...
                    Q(app.ElementNodes(index,2)*2-1,1);Q(app.ElementNodes(index,2)*2,1);...
                    Q(app.ElementNodes(index,3)*2-1,1);Q(app.ElementNodes(index,3)*2,1)];

                beta = [coord(nodes(index,2),2) - coord(nodes(index,3),2),
                    coord(nodes(index,3),2) - coord(nodes(index,1),2),
                    coord(nodes(index,1),2) - coord(nodes(index,2),2)];
                gamma = [coord(nodes(index,3),1) - coord(nodes(index,2),1),
                    coord(nodes(index,1),1) - coord(nodes(index,3),1),
                    coord(nodes(index,2),1) - coord(nodes(index,1),1)];
                B = (1/(2*A)) * [beta(1) 0 beta(2) 0 beta(3) 0;
                    0 gamma(1) 0 gamma(2) 0 gamma(3);
                    gamma(1) beta(1) gamma(2) beta(2) gamma(3) beta(3)];
                app.stress(index,1:3)=[D*B*elementDisplacement]';

                eqvStress = [];
                eqvStress = zeros(size(app.stress,1),1);
                eqvStress(:,1) = sqrt(app.stress(:,1).^2 - app.stress(:,1) .* app.stress(:,2) + app.stress(:,2).^2 + 3 * app.stress(:,3).^2);
                app.stress(:,4) = eqvStress;


                %% Calculating Strain

                app.strain(index,:)=[B*elementDisplacement]';
                %% calculting internal force
                A = (coord(nodes(index,1),1)*(coord(nodes(index,2),2)-coord(nodes(index,3),2)) ...
                    +coord(nodes(index,2),1)*(coord(nodes(index,3),2)-coord(nodes(index,1),2)) ...
                    +coord(nodes(index,3),1)*(coord(nodes(index,1),2)-coord(nodes(index,2),2)))/2;
                k = t*A*B'*D*B;
                app.internalForce(index,:)=[k*elementDisplacement]';
                %% Calculating Nodal Forces
                app.nodalForce([app.ElementNodes(index,1),app.ElementNodes(index,2), app.ElementNodes(index,3)],1:2)=...
                    app.nodalForce([app.ElementNodes(index,1),app.ElementNodes(index,2), app.ElementNodes(index,3)],1:2)+...
                    [transpose(app.internalForce(index,[1,3,5])),transpose(app.internalForce(index,[2,4,6]))];
            end
            %% Calculating Reactions


            for index=1:size(app.NodeCoordinates,1)
                if ~isnan(boundaries(index*2-1,1))
                    R=-c*(Q(index*2-1,1)-boundaries(index*2-1,1));


                else
                    R=totalForce(index*2-1,1);

                end
                app.reaction(index,1)=R;
                if ismember(index, app.CornerNodes)
                    app.cReaction(find(app.CornerNodes==index),1)=R;

                end

            end

            for index=1:size(app.NodeCoordinates,1)
                if ~isnan(boundaries(index*2,1))
                    R=-c*(Q(index*2,1)-boundaries(index*2,1));


                else
                    R=totalForce(index*2,1);
                end
                app.reaction(index,2)=R;
                if ismember(index, app.CornerNodes)
                    app.cReaction(find(app.CornerNodes==index),2)=R;

                end

            end


            format long


            %% enabling buttons
            app.CornerdButton.Enable="on";
            app.NodesdButton.Enable="on";
            app.StressButton.Enable="on";
            app.StrainButton.Enable="on";
            app.CornerForceButton.Enable="on";
            app.NodesForceButton.Enable="on";
            app.KButton.Enable="on";
            app.InternalForcesButton.Enable="on";
            app.WritetoANSYSButton.Enable="on";
            %% Preparing Q
            app.Q=[Q(((1:size(app.NodeCoordinates,1))*2-1),1),Q(((1:size(app.NodeCoordinates,1))*2),1)];
            app.cornerD=[Q(app.CornerNodes*2-1,1),Q(app.CornerNodes*2,1)];


            app.draw=1;
            app.graph();


            if app.YieldStressEditField.Value~=0 && max(app.stress(:,4))>app.YieldStressEditField.Value
                app.ProcessingLabel.Text="Check for Failure";
            else
                app.ProcessingLabel.Text="Ready for Input";
            end
        end

        % Value changed function: ThicknessEditField
        function ThicknessEditFieldValueChanged(app, event)
            value = app.ThicknessEditField.Value;
            app.draw=0;
            app.graph();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: StressButton
        function StressButtonPushed(app, event)
            app.newFig1 = uifigure('Name', 'Stresses', 'Position', [100, 200, 650, 600]);
            contourFig = uifigure('Name','Stress Contour','Position', [500, 200, 650, 600]);
            p = app.NodeCoordinates;
            cl = app.ElementNodes;
            Q = app.Q;
            neu = app.PoissonsRatioEditField.Value;
            factor=app.DeformationscaleEditField.Value;


            maxStress = max(app.stress(:,4));
            minStress = min(app.stress(:,4));
            eqvStress=app.stress(:,4);

            stressColors = [];
            stressColors = zeros(size(eqvStress,1),1);
            stressColors(:,1) = (eqvStress(:,1)- minStress) ./ (maxStress - minStress);

            contourAxes = uiaxes(contourFig,'Position', [50, 50, 550, 550]);

            patch(contourAxes,'Faces',cl,'Vertices',p+factor*app.Q(:,1:2),'FaceVertexCData',stressColors*maxStress,'FaceColor','flat');
            colorbar(contourAxes);
            colormap(contourAxes,"jet");
            contourAxes.XLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];
            contourAxes.YLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];


            uit = uitable(app.newFig1,'Position', [50, 50, 550, 550]);
            uit.ColumnName = {'Stress in X', 'Stress in Y','Shear XY', 'Von-Mises Stress'};
            uit.Data = app.stress;
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
            app.newFig2 = uifigure('Name', 'Stiffness Matrix', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig2,'Position', [50, 50, 550, 550]);
            uit.Data = app.originalK;
        end

        % Button pushed function: StrainButton
        function StrainButtonPushed(app, event)


            p = app.NodeCoordinates;
            cl = app.ElementNodes;
            factor=app.DeformationscaleEditField.Value;

            principleStrain(:,1) = (app.strain(:,1) + app.strain(:,2))/2 + sqrt(((app.strain(:,1) - app.strain(:,2))/2).^2 + (app.strain(:,3)/2).^2);
            app.strain(:,4) = principleStrain;

            maxStrain = max(principleStrain);
            minStrain = min(principleStrain);

            strainColor(:,1) = (principleStrain(:,1) - minStrain) ./ (maxStrain - minStrain);

            contourFig = uifigure('Name','Strain Contour','Position', [500, 200, 650, 600]);
            contourAxes = uiaxes(contourFig,'Position', [50, 50, 550, 550]);

            patch(contourAxes,'Faces',cl,'Vertices',p+factor*app.Q(:,1:2),'FaceVertexCData',strainColor*maxStrain,'FaceColor','flat');
            colorbar(contourAxes);
            colormap(contourAxes,"jet");
            contourAxes.XLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];
            contourAxes.YLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];

            app.newFig3 = uifigure('Name', 'Strain', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig3,'Position', [50, 50, 550, 550]);
            uit.ColumnName = {'Strain in X', 'Strain in Y','Angle Strain XY', '1st Principal Strain'};
            uit.Data = app.strain;
        end

        % Button pushed function: NodesdButton
        function NodesdButtonPushed(app, event)


            p = app.NodeCoordinates;
            cl = app.ElementNodes;
            Q = app.Q;
            neu = app.PoissonsRatioEditField.Value;
            factor=app.DeformationscaleEditField.Value;

            USUM(:,1) = sqrt(Q(:,1).^2 + Q(:,2).^2);

            contourFig = uifigure('Name','Displacement Contour','Position', [500, 200, 650, 600]);

            contourAxes = uiaxes(contourFig,'Position', [50, 50, 550, 550]);

            maxUSUM = max(USUM);
            minUSUM = min(USUM);

            UColors = [];
            UColors = zeros(size(USUM,1),1);
            UColors(:,1) = (USUM(:,1)- minUSUM) ./ (maxUSUM - minUSUM);
            app.Q(:,3) = USUM;

            patch(contourAxes,'Faces',cl,'Vertices',p+factor*app.Q(:,1:2),'FaceVertexCData',UColors*maxUSUM,'FaceColor','interp');
            colorbar(contourAxes);
            colormap(contourAxes,"jet");
            contourAxes.XLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];
            contourAxes.YLim=[-0.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value),1.2*max(app.WidthEditField_2.Value,app.HeightEditField.Value)];

            app.newFig4 = uifigure('Name', 'Nodes Displacement', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig4,'Position', [50, 50, 550, 550]);
            uit.ColumnName = {'X', 'Y', 'USUM'};
            format("long");
            uit.Data = app.Q;

        end

        % Button pushed function: CornerdButton
        function CornerdButtonPushed(app, event)
            app.newFig5 = uifigure('Name', 'Corner Nodes Displacement', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig5,'Position', [50, 50, 550, 550]);
            uit.ColumnName = {'X', 'Y'};
            format long
            uit.Data = app.cornerD;
        end

        % Button pushed function: NodesForceButton
        function NodesForceButtonPushed(app, event)
            app.newFig6 = uifigure('Name', 'Reaction Forces', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig6,'Position', [50, 50, 550, 550]);
            uit.ColumnName = { 'X', 'Y'};
            uit.Data = app.reaction;
        end

        % Button pushed function: CornerForceButton
        function CornerForceButtonPushed(app, event)
            app.newFig7 = uifigure('Name', 'Reaction Forces', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig7,'Position', [50, 50, 550, 550]);
            uit.ColumnName = {'X','Y'};
            uit.Data = app.cReaction;
        end

        % Button pushed function: InternalForcesButton
        function InternalForcesButtonPushed(app, event)
            app.newFig8 = uifigure('Name', 'Reaction Forces', 'Position', [100, 200, 650, 600]);
            uit = uitable(app.newFig8,'Position', [50, 50, 550, 550]);
            uit.ColumnName = {'Node i-X','Node i-Y','Node j-X','Node j-Y','Node m-X','Node m-Y'};
            uit.Data = app.internalForce;
        end

        % Button pushed function: WritetoANSYSButton
        function WritetoANSYSButtonPushed(app, event)
            % app.newFig9 = uifigure('Name', 'Reaction Forces', 'Position', [100, 200, 650, 600]);
            % uit = uitable(app.newFig9,'Position', [50, 50, 550, 550]);
            % uit.ColumnName = {'X','Y'};
            % uit.Data = app.nodalForce;


            %% Write APDL Text File

            % Local Variables
            E = app.EEditField.Value;
            neu = app.PoissonsRatioEditField.Value;
            kGlobal = zeros(length(app.NodeCoordinates)*2);
            t = app.ThicknessEditField.Value;
            coord = app.NodeCoordinates;
            nodes = app.ElementNodes;
            hDiv=app.HorizontalDivisionsEditField.Value;
            vDiv=app.VerticalDivisionsEditField.Value;
            width=app.WidthEditField_2.Value;
            height=app.HeightEditField.Value;

            text = "KEYW,PR_SET,1" + newline + ...
                "KEYW,PR_STRUC,1" + newline + ...
                "KEYW,PR_THERM,0" + newline + ...
                "KEYW,PR_FLUID,0" + newline + ...
                "KEYW,PR_ELMAG,0" + newline + ...
                "KEYW,MAGNOD,0" + newline + ...
                "KEYW,MAGEDG,0" + newline + ...
                "KEYW,MAGHFE,0" + newline + ...
                "KEYW,MAGELC,0" + newline + ...
                "KEYW,PR_MULTI,0" + newline + ...
                "/GO" + newline + ...
                "/PREP7" + newline + ...
                "ET,1,PLANE182" + newline + ...
                "KEYOPT,1,1,0" + newline;

            if app.PlaneStressButton.Value
                text = text + "KEYOPT,1,3,3" + newline + ...
                    "KEYOPT,1,6,0" + newline + ...
                    "R,1,"+t + newline;
            else
                text = text + "KEYOPT,1,3,2"+ newline + ...
                    "KEYOPT,1,6,0" +newline;
            end

            text = text + "MPTEMP,,,,,,,," + newline + ...
                "MPTEMP,1,0 " + newline + ...
                "MPDATA,EX,1,," + E + newline + ...
                "MPDATA,PRXY,1,," + neu + newline + ...
                "RECTNG,0," + width + ",0,"+ height +"," + newline + ...
                "MOPT,AMESH,DEFA" + newline + ...
                "MOPT,QMESH,DEFA" + newline + ...
                "MOPT,VMESH,DEFA" + newline + ...
                "MOPT,TIMP,1" + newline + ...
                "MOPT,PYRA,ON" + newline + ...
                "MOPT,AORD,0" + newline + ...
                "MOPT,SPLIT,1" + newline + ...
                "MSHKEY,1" + newline + ...
                "MSHMID,0" + newline + ...
                "MSHPATTERN,2" + newline + ...
                "KEYW,ACCEPT,0" + newline + ...
                "MSHAPE,1,2D" + newline + ...
                "MSHAPE,0,3D" + newline + ...
                "ESIZE,0," + max(hDiv,vDiv) + newline + ...
                "AMESH,ALL" + newline;

            for i=1:4
                if ~isnan(app.Displacements(i,1))
                    text = text + ...
                        "FLST,2,1,3,ORDE,1" + newline + ...
                        "FITEM,2," + i + newline + ...
                        "DK,P51X,UX," + app.Displacements(i,1) + newline;
                end
                if ~isnan(app.Displacements(i,2))
                    text = text + ...
                        "FLST,2,1,3,ORDE,1" + newline + ...
                        "FITEM,2," + i + newline + ...
                        "DK,P51X,UY," + app.Displacements(i,2) + newline;
                end
            end
            for i=5:8
                if ~isnan(app.Displacements(i,1))
                    text = text + ...
                        "FLST,2,1,4,ORDE,1" + newline + ...
                        "FITEM,2," + (i-4) + newline + ...
                        "DL,P51X,,UX," + app.Displacements(i,1) + newline;
                end
                if ~isnan(app.Displacements(i,2))
                    text = text + ...
                        "FLST,2,1,4,ORDE,1" + newline + ...
                        "FITEM,2," + (i-4) + newline + ...
                        "DL,P51X,,UY," + app.Displacements(i,2) + newline;
                end
            end
            for i=1:4
                if app.Forces(i,1)~=0
                    text = text + ...
                        "FLST,2,1,3,ORDE,1" + newline + ...
                        "FITEM,2," + i + newline + ...
                        "FK,P51X,FX," + app.Forces(i,1) + newline;
                end
                if app.Forces(i,2)~=0
                    text = text + ...
                        "FLST,2,1,3,ORDE,1" + newline + ...
                        "FITEM,2," + i + newline + ...
                        "FK,P51X,FY," + app.Forces(i,2) + newline;
                end
            end

            if app.Forces(5,2)~=0
                text = text + ...
                    "FLST,2,1,4,ORDE,1" + newline + ...
                    "FITEM,2," + 1 + newline + ...
                    "SFL,P51X,PRES," + app.Forces(5,2) + newline;
            end
            if app.Forces(6,1)~=0
                text = text + ...
                    "FLST,2,1,4,ORDE,1" + newline + ...
                    "FITEM,2," + 2 + newline + ...
                    "SFL,P51X,PRES," + (-app.Forces(6,1)) + newline;
            end
            if app.Forces(7,2)~=0
                text = text + ...
                    "FLST,2,1,4,ORDE,1" + newline + ...
                    "FITEM,2," + 3 + newline + ...
                    "SFL,P51X,PRES," + (-app.Forces(7,2)) + newline;
            end
            if app.Forces(8,1)~=0
                text = text + ...
                    "FLST,2,1,4,ORDE,1" + newline + ...
                    "FITEM,2," + 4 + newline + ...
                    "SFL,P51X,PRES," + app.Forces(8,1) + newline;
            end

            text = text + ...
                "FINISH" + newline + ...
                "/SOL" + newline + ...
                "SOLVE" + newline + ...
                "FINISH" + newline + ...
                "/POST1" + newline + ...
                "SET, LAST" + newline + ...
                "/WIND,1,TOP" + newline + ...
                "/WIND,2,LBOT" + newline + ...
                "/WIND,3,RBOT" + newline + ...
                "/GCMD,1,PLNSOL, U,SUM, 0, 1.0" + newline + ...
                "/GCMD,2,PLESOL,S,EQV, 0, 1.0" + newline + ...
                "/GCMD,3,PLESOL,EPTO,1, 0, 1.0" + newline + ...
                "GPLOT";


            writelines(text,"ANSYS Commands.lgw");
        end

        % Button pushed function: AnimateButton
        function AnimateButtonPushed(app, event)
            app.animation=1;
            app.graph;
        end

        % Value changed function: DeformationscaleEditField
        function DeformationscaleEditFieldValueChanged(app, event)
            value = app.DeformationscaleEditField.Value;
            app.graph();
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.draw=0;
            app.graph();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
             app.draw=0;
            app.graph();
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
             app.draw=0;
            app.graph();
        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            value = app.XEditField.Value;
             app.draw=0;
            app.graph();
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
             app.draw=0;
            app.graph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WidthEditField_2Label = uilabel(app.UIFigure);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField_2.Limits = [0 Inf];
            app.WidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthEditField_2ValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThicknessEditField.Limits = [0 Inf];
            app.ThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessEditFieldValueChanged, true);
            app.HorizontalDivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalDivisionsEditFieldLabel.Text = 'Horizontal Divisions';
            app.HorizontalDivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontalDivisionsEditField.Limits = [1 Inf];
            app.HorizontalDivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalDivisionsEditFieldValueChanged, true);
            app.HorizontalDivisionsEditField.Value = 1;
            app.VerticalDivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalDivisionsEditFieldLabel.Text = 'Vertical Divisions';
            app.VerticalDivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalDivisionsEditField.Limits = [1 Inf];
            app.VerticalDivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalDivisionsEditFieldValueChanged, true);
            app.VerticalDivisionsEditField.Value = 1;
            app.EEditField = uieditfield(app.UIFigure, 'numeric');
            app.EEditField.Limits = [0 Inf];
            app.EEditField.Value = 200000000000;
            app.PoissonsRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoissonsRatioEditField.Limits = [0 Inf];
            app.PoissonsRatioEditField.Value = 0.3;
            app.YieldStressEditField = uieditfield(app.UIFigure, 'numeric');
            app.YieldStressEditField.Limits = [0 Inf];
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {'Node 1'; 'Node 2'; 'Node 3'; 'Node 4'; 'Side 1'; 'Side 2'; 'Side 3'; 'Side 4'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SolveButton = uibutton(app.UIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.PlaneDimensionsLabel = uilabel(app.UIFigure);
            app.PlaneDimensionsLabel.Text = 'Plane Dimensions';
            app.MeshOptionsLabel = uilabel(app.UIFigure);
            app.MeshOptionsLabel.Text = 'Mesh Options';
            app.BoundaryConditionsButtonGroup = uibuttongroup(app.UIFigure);
            app.BoundaryConditionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BoundaryConditionsButtonGroupSelectionChanged, true);
            app.BoundaryConditionsButtonGroup.Title = 'Boundary Conditions';
            app.DisplacementButton = uitogglebutton(app.BoundaryConditionsButtonGroup);
            app.DisplacementButton.Text = 'Displacement';
            app.DisplacementButton.Value = true;
            app.ForceButton = uitogglebutton(app.BoundaryConditionsButtonGroup);
            app.ForceButton.Text = 'Force';
            app.ProcessingLabel = uilabel(app.UIFigure);
            app.ProcessingLabel.Text = 'Processing';
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E';
            app.PoissonsRatioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.ThicknessEditFieldLabel = uilabel(app.UIFigure);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.MaterialPropertiesLabel = uilabel(app.UIFigure);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.CornerdButton = uibutton(app.UIFigure, 'push');
            app.CornerdButton.ButtonPushedFcn = createCallbackFcn(app, @CornerdButtonPushed, true);
            app.CornerdButton.Enable = 'off';
            app.CornerdButton.Text = 'Corner d';
            app.ShowLabel = uilabel(app.UIFigure);
            app.ShowLabel.Text = 'Show';
            app.CornerForceButton = uibutton(app.UIFigure, 'push');
            app.CornerForceButton.ButtonPushedFcn = createCallbackFcn(app, @CornerForceButtonPushed, true);
            app.CornerForceButton.Enable = 'off';
            app.CornerForceButton.Text = 'Corner Force';
            app.StressButton = uibutton(app.UIFigure, 'push');
            app.StressButton.ButtonPushedFcn = createCallbackFcn(app, @StressButtonPushed, true);
            app.StressButton.Enable = 'off';
            app.StressButton.Text = 'Stress';
            app.NodesdButton = uibutton(app.UIFigure, 'push');
            app.NodesdButton.ButtonPushedFcn = createCallbackFcn(app, @NodesdButtonPushed, true);
            app.NodesdButton.Enable = 'off';
            app.NodesdButton.Text = 'Nodes d';
            app.NodesForceButton = uibutton(app.UIFigure, 'push');
            app.NodesForceButton.ButtonPushedFcn = createCallbackFcn(app, @NodesForceButtonPushed, true);
            app.NodesForceButton.Enable = 'off';
            app.NodesForceButton.Text = 'Nodes Force';
            app.StrainButton = uibutton(app.UIFigure, 'push');
            app.StrainButton.ButtonPushedFcn = createCallbackFcn(app, @StrainButtonPushed, true);
            app.StrainButton.Enable = 'off';
            app.StrainButton.Text = 'Strain';
            app.KButton = uibutton(app.UIFigure, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Enable = 'off';
            app.KButton.Text = 'K';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PlaneStressButton = uiradiobutton(app.ButtonGroup);
            app.PlaneStressButton.Text = 'Plane Stress';
            app.PlaneStressButton.Value = true;
            app.PlaneStrainButton = uiradiobutton(app.ButtonGroup);
            app.PlaneStrainButton.Text = 'Plane Strain';
            app.InternalForcesButton = uibutton(app.UIFigure, 'push');
            app.InternalForcesButton.ButtonPushedFcn = createCallbackFcn(app, @InternalForcesButtonPushed, true);
            app.InternalForcesButton.Enable = 'off';
            app.InternalForcesButton.Text = 'Internal Forces';
            app.WritetoANSYSButton = uibutton(app.UIFigure, 'push');
            app.WritetoANSYSButton.ButtonPushedFcn = createCallbackFcn(app, @WritetoANSYSButtonPushed, true);
            app.WritetoANSYSButton.Enable = 'off';
            app.WritetoANSYSButton.Text = 'Write to ANSYS';
            app.DeformationscaleEditFieldLabel = uilabel(app.UIFigure);
            app.DeformationscaleEditFieldLabel.Text = 'Deformation scale';
            app.DeformationscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeformationscaleEditField.Limits = [1 Inf];
            app.DeformationscaleEditField.ValueChangedFcn = createCallbackFcn(app, @DeformationscaleEditFieldValueChanged, true);
            app.DeformationscaleEditField.Value = 1;
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.FPSEditField.Limits = [0 Inf];
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = ' Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Limits = [0 Inf];
            app.AnimateButton = uibutton(app.UIFigure, 'push');
            app.AnimateButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateButtonPushed, true);
            app.AnimateButton.Text = 'Animate';
            app.YieldStressEditFieldLabel = uilabel(app.UIFigure);
            app.YieldStressEditFieldLabel.Text = 'Yield Stress';
            app.omegaLabel = uilabel(app.UIFigure);
            app.omegaLabel.Text = 'ω';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'ρ';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.MadewithloveinAUCEDLabel = uilabel(app.UIFigure);
            app.MadewithloveinAUCEDLabel.Text = {'Made with love in AUC'; 'E&D'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Copy-of-AUC-Logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlaneStress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end